classdef GenerateReport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ReportFolderEditFieldLabel  matlab.ui.control.Label
        ReportFolderEditField       matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        OverallTimeCheckBox         matlab.ui.control.CheckBox
        SongsPanel                  matlab.ui.container.Panel
        InstrumentsPanel            matlab.ui.container.Panel
        ClearChecksButton           matlab.ui.control.Button
        CreateReportButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        pos = struct(...
            'left',6,...
            'curbot',1,...
            'width',180,...
            'height',22,...
            'buffer',21);
    end
    
    methods (Access = private)
        
        function addToPanel(app, panel, names)
            if numel(names) > 9
                app.addFromBottom(panel, names)
            else
                app.addFromTop(panel, names);
            end
        end
        
        function addFromBottom(app, panel, names)
            app.pos.curbot = 1;
            for ii=1:numel(names)
                posit = [app.pos.left,app.pos.curbot,app.pos.width,app.pos.height];
                uicheckbox(panel,...
                    'Text', names{ii},...
                    'Position', posit);
                app.pos.curbot = app.pos.curbot + app.pos.buffer;
            end
        end
        
        function addFromTop(app, panel, names)
            app.pos.curbot = 179;
            for ii=1:numel(names)
                posit = [app.pos.left,app.pos.curbot,app.pos.width,app.pos.height];
                uicheckbox(panel,...
                    'Text', names{ii},...
                    'Position', posit);
                app.pos.curbot = app.pos.curbot - app.pos.buffer;
            end
        end
        
        function generateReport(app, folder)
            % get checks
            songChecks = app.getChecks(app.SongsPanel);
            instrChecks = app.getChecks(app.InstrumentsPanel);
            
            songData = app.getData(songChecks, folder);
            instrData = app.getData(instrChecks, folder);
            
            % graph data
            
        end
        
        
    end
    
    methods (Static)
        function checks = getChecks(panel)
            checks = {};
            for ii=1:numel(panel.Children)
                if panel.Children(ii).Value
                    checks = [checks, {panel.Children(ii).Text}];
                end
            end
        end
        
        function dataMap = getData(checks, folder)
            dataMap = containers.Map('KeyType','char','ValueType','any');
            cd (folder);
            files = dir('*.txt');
            
            fileNames = {files.name};
            
            for ii=1:numel(checks)
                dataMap(checks{ii}) = app.getCheckNameData(fileNames, checks{ii});
            end
        end
        
        function data = getCheckNameData(fileNames, chkName)
            data = [];
            for jj=1:numel(fileNames)
                if(strsplit(fileNames{jj}) == 3)
                    data = [data; [app.getDatFromFile(fileNames{jj}, 'Date'), app.getDataFromFile(fileNames{jj},chkName)]];
                end
            end
        end
        
        function val = getDataFromFile(file, name)
            report = DataHandler(file);
            data = report.getData;
            val = data(contains(data, name));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, songs, instruments)
            app.addToPanel(app.SongsPanel, songs);
            app.addToPanel(app.InstrumentsPanel, instruments);
        end

        % Button pushed function: CreateReportButton
        function CreateReportButtonPushed(app, event)
            folder = app.ReportFolderEditField.Value;
            if(folder)
                app.generateReport(folder);
            else
                uiconfirm(app.UIFigure,"No folder selected",...
                    "Warning",...
                    'Icon', 'warning');
                
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            folder = uigetdir('*.txt', "Select Folder");
            app.ReportFolderEditField.Value = folder;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReportFolderEditFieldLabel = uilabel(app.UIFigure);
            app.ReportFolderEditFieldLabel.Text = 'Report Folder';
            app.ReportFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.OverallTimeCheckBox = uicheckbox(app.UIFigure);
            app.OverallTimeCheckBox.Text = 'Overall Time';
            app.SongsPanel = uipanel(app.UIFigure);
            app.SongsPanel.Title = 'Songs';
            app.InstrumentsPanel = uipanel(app.UIFigure);
            app.InstrumentsPanel.Title = 'Instruments';
            app.ClearChecksButton = uibutton(app.UIFigure, 'push');
            app.ClearChecksButton.Text = 'Clear Checks';
            app.CreateReportButton = uibutton(app.UIFigure, 'push');
            app.CreateReportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReportButtonPushed, true);
            app.CreateReportButton.Text = 'Create Report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateReport(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end