classdef timbr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        timbrApp                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        PracticeTrackerTab            matlab.ui.container.Tab
        PracticeNotesTextAreaLabel    matlab.ui.control.Label
        PracticeNotesTextArea         matlab.ui.control.TextArea
        DateEditFieldLabel            matlab.ui.control.Label
        DateEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.EditField
        GoalLabel                     matlab.ui.control.Label
        GoalTextArea                  matlab.ui.control.TextArea
        InstrumentDropDownLabel       matlab.ui.control.Label
        InstrumentDropDown            matlab.ui.control.DropDown
        SubmitSessionButton           matlab.ui.control.Button
        AMPMDropDown                  matlab.ui.control.DropDown
        SongDatabaseButton            matlab.ui.control.Button
        SongPieceDropDownLabel        matlab.ui.control.Label
        SongDropDown                  matlab.ui.control.DropDown
        SongPieceDropDown_2Label      matlab.ui.control.Label
        SongDropDown_2                matlab.ui.control.DropDown
        SongPieceDropDown_3Label      matlab.ui.control.Label
        SongDropDown_3                matlab.ui.control.DropDown
        HoursEditFieldLabel           matlab.ui.control.Label
        HoursEditField                matlab.ui.control.NumericEditField
        MinutesEditFieldLabel         matlab.ui.control.Label
        MinutesEditField              matlab.ui.control.NumericEditField
        MinutesEditField_2Label       matlab.ui.control.Label
        MinutesEditField_2            matlab.ui.control.NumericEditField
        HoursEditField_2Label         matlab.ui.control.Label
        HoursEditField_2              matlab.ui.control.NumericEditField
        MinutesEditField_3Label       matlab.ui.control.Label
        MinutesEditField_3            matlab.ui.control.NumericEditField
        HoursEditField_3Label         matlab.ui.control.Label
        HoursEditField_3              matlab.ui.control.NumericEditField
        ClearInputsButton             matlab.ui.control.Button
        RecallSessionButton           matlab.ui.control.Button
        Circleof5thsTab               matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        GuitarChordsTab               matlab.ui.container.Tab
        GuitarChords_CAGEDLB          matlab.ui.control.ListBox
        GuitarChords_CO5Axes          matlab.ui.control.UIAxes
        GuitarChords_DisplayChordBtn  matlab.ui.control.Button
        KeyDropDownLabel              matlab.ui.control.Label
        GuitarChords_KeyDD            matlab.ui.control.DropDown
        ChordDropDownLabel            matlab.ui.control.Label
        GuitarChords_ChordDD          matlab.ui.control.DropDown
        SongDatabaseTab               matlab.ui.container.Tab
        AddSongButton                 matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        DeleteSongButton              matlab.ui.control.Button
        SearchButton                  matlab.ui.control.Button
        InstrumentsTab                matlab.ui.container.Tab
        AddInstrumentButton           matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
        Tab                           matlab.ui.container.Tab
    end

    
    properties (Access = public)
        practiceLog % Practice Tracker object for Practice Tracker tab management
        gcRealizer
    end
    
    methods (Access = public)       
        function addSongToDatabase(app, name, artist, url)
            curData = app.UITable.Data; % Get current data from table
            newData = [curData; [{name}, {artist}, {url}]]; % append row to the bottom
            app.UITable.Data = newData; % Add the data back to the table
            app.sortSongDatabase; % Resort the entries
            app.practiceLog.populateSongsFromDataBase;
        end
        
        function deleteSongInDatabase(app, name)
            answer = questdlg('Are you sure you want to delete this song?', 'Delete Song', ...
           	    'Yes','No','No');
            switch answer % Handle response
                case 'Yes'
                    disp([answer 'Song deleted'])
                    app.UITable.Data(ismember(app.UITable.Data(:,1),name),:) = []; % Search table for name and clear that row
                    app.sortSongDatabase; % Resort the entries
                case 'No'
                    disp([answer 'Song not deleted'])
            end
        end
        
        function sortSongDatabase(app)
            data = app.UITable.Data;    % Get current data from table
            sortedData = sortrows(data, 1); % Sort the data by the first column
            app.UITable.Data = sortedData; % Add the data back to the table
        end
        
        
        function AddInstrumentToDatabase(app, instrument)
            curData = app.UITable2.Data; % Get current data from table
            newData = [curData; [{instrument}, {0}]]; % append row to the bottom
            app.UITable2.Data = newData; % Add the data back to the table
            app.practiceLog.populateInstrumentsFromDataBase;
        end
    end
    
    methods (Static)
        function results = isValidDate(in)
            date = strsplit(in, '/');
            if ~(numel(date) == 3)
                results = false;
                return
            end
            
            month = str2double(date{1});
            day = str2double(date{2});
            year = str2double(date{3});
            
            if isnan(month) || isnan(day) || isnan(year)
                results = false;
                return
            end
            % test month for correct range
            if (month < 0) || (month > 12)
                results = false;
                % test day for correct range
            elseif (day < 0) || (day> 31)
                results = false;
                % test year for correct range
            elseif (year < 1000 || year > 3000)
                results = false;
                % If we reach here, date is correct format
            else
                results = true;
            end
        end
        
        function results = isValidTime(in)
            date = strsplit(in, ':');
            if ~(numel(date) == 2)
                results = false;
                return
            end
            
            hour = str2double(date{1});
            min = str2double(date{2});
            
            if isnan(hour) || isnan(min)
                results = false;
                return
            end
            % test hour for correct range
            if (hour < 0) || (hour > 12)
                results = false;
                % test min for correct range
            elseif (min < 0) || (min > 60)
                results = false;
            else
                results = true;
            end
        end
    end
    
    methods (Access = private)
        
        function dispInitImages(app)
            
            % Display Circle of fifths
            I = imshow([pwd,'\ProjectCode\TIMBR_DAT\circlefifths.jpg'], 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            % Display initial chord
            I3 = imshow([pwd,'\ProjectCode\TIMBR_DAT\A.png'], 'Parent', app.GuitarChords_CO5Axes, ...
                'XData', [1 app.GuitarChords_CO5Axes.Position(3)], ...
                'YData', [1 app.GuitarChords_CO5Axes.Position(4)]);
            % Set limits of axes
            app.GuitarChords_CO5Axes.XLim = [0 I3.XData(2)];
            app.GuitarChords_CO5Axes.YLim = [0 I3.YData(2)];
            set(app.GuitarChords_CO5Axes,'xticklabel',{[]},'yticklabel',{[]})
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.practiceLog = PracticeTracker(app); % Creates the practiceTracker object
            app.practiceLog.setUserData; % sets user data for the practice tracker elements
            app.practiceLog.populateSongsFromDataBase;
            app.addSongToDatabase('Fur elise', 'Beethoven', 'bomb.com');
            app.addSongToDatabase('Shallow', 'Lady Gaga', 'bomb.com');
            app.addSongToDatabase('Old Devil Moon', 'Josh Groban', 'bomb.com');
            app.AddInstrumentToDatabase('Guitar');
            app.AddInstrumentToDatabase('Piano');
            app.AddInstrumentToDatabase('Drums');
            app.practiceLog.populateInstrumentsFromDataBase;
            app.dispInitImages;
            app.gcRealizer = GuitarChordRealizer(app);
            app.gcRealizer.concatChords(app.GuitarChords_KeyDD.Value);
        end

        % Button pushed function: SubmitSessionButton
        function SubmitSessionButtonPushed(app, event)
            if ~(app.isValidDate(app.DateEditField.Value) && app.isValidTime(app.TimeEditField.Value))
                uiconfirm(app.timbrApp,'Invalid Time or date, please enter date in format mm/dd/yyyy, and time in format hh:mm','Invalid Date Time',...
                    'Icon','warning');
                return
            else
                app.practiceLog.submitSession;
                uiconfirm(app.timbrApp,'Session Submitted','Session Submit',...
                    'Icon','success');
            end
        end

        % Value changed function: DateEditField
        function DateEditFieldValueChanged(app, event)
            value = app.DateEditField.Value;
            if app.isValidDate(value)
                return
            else
                uiconfirm(app.timbrApp,'Invalid Date, please enter date in format mm/dd/yyyy','Invalid Date',...
                    'Icon','warning');
            end
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            if app.isValidTime(value)
                return
            else
                uiconfirm(app.timbrApp,'Invalid Time, please enter time in format hh:mm','Invalid Time',...
                    'Icon','warning');
            end
        end

        % Button pushed function: ClearInputsButton
        function ClearInputsButtonPushed(app, event)
            app.practiceLog.clearPracticeTracker;
        end

        % Button pushed function: RecallSessionButton
        function RecallSessionButtonPushed(app, event)
            [file,path] = uigetfile('*.txt',... % Opens a dialog that lets you select png files
                'Select a file');
            if(file)
            app.practiceLog.getPracticeData([path, file]);
            end
        end

        % Value changed function: GuitarChords_KeyDD
        function GuitarChords_KeyDDValueChanged(app, event)
            key = app.GuitarChords_KeyDD.Value;
            
            %Function for updating Circle of 5ths Image
            app.gcRealizer.updateCO5PNG(key);
            
            %Concat Key chords to Chord Drop Down
            app.gcRealizer.concatChords(key);
            
            %Retrieve value selected in Chord Drop Down
            value = app.GuitarChords_ChordDD.Value;
            
            %Method for Concat Caged Slider
            app.gcRealizer.concatCaged(value);   
        end

        % Button pushed function: GuitarChords_DisplayChordBtn
        function GuitarChords_DisplayChordBtnButtonPushed(app, event)
            %Logic for displaying guitar chord
            app.gcRealizer.dispGuitarChord;
        end

        % Button pushed function: AddSongButton
        function AddSongButtonPushed(app, event)
            prompt = {'Enter song name','Enter song artist(s)','Enter URL to purchase sheet music'};
            dlgtitle = 'Add Song';
            dims = [1 35];
            definput = {'','',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            app.addSongToDatabase(answer{1},answer{2},answer{3});
        end

        % Button pushed function: DeleteSongButton
        function DeleteSongButtonPushed(app, event)
            prompt = {'Enter song name'};
            dlgtitle = 'Delete Song';
            dims = [1 35];
            definput = {''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.deleteSongInDatabase(answer{1});
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            prompt = {'Enter song name','Enter song artist(s)'};
            dlgtitle = 'Search for a Song';
            dims = [1 35];
            definput = {'',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
        end

        % Button pushed function: SongDatabaseButton
        function SongDatabaseButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SongDatabaseTab;
        end

        % Value changed function: GuitarChords_ChordDD
        function GuitarChords_ChordDDValueChanged(app, event)
            %Retrieve value selected in Chord Drop Down
            value = app.GuitarChords_ChordDD.Value;
            
	    %Method for Concat Caged Slider
            app.gcRealizer.concatCaged(value);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.timbrApp = uifigure;
            app.timbrApp.Name = 'UI Figure';
            app.FileMenu = uimenu(app.timbrApp);
            app.FileMenu.Text = 'File';
            app.SettingsMenu = uimenu(app.timbrApp);
            app.SettingsMenu.Text = 'Settings';
            app.OptionsMenu = uimenu(app.timbrApp);
            app.OptionsMenu.Text = 'Options';
            app.TabGroup = uitabgroup(app.timbrApp);
            app.PracticeTrackerTab = uitab(app.TabGroup);
            app.PracticeTrackerTab.Title = 'Practice Tracker';
            app.PracticeNotesTextAreaLabel = uilabel(app.PracticeTrackerTab);
            app.PracticeNotesTextAreaLabel.Text = 'Practice Notes';
            app.PracticeNotesTextArea = uitextarea(app.PracticeTrackerTab);
            app.DateEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.PracticeTrackerTab, 'text');
            app.DateEditField.ValueChangedFcn = createCallbackFcn(app, @DateEditFieldValueChanged, true);
            app.TimeEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.PracticeTrackerTab, 'text');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.GoalLabel = uilabel(app.PracticeTrackerTab);
            app.GoalLabel.Text = 'What''s your goal for this practice session? ';
            app.GoalTextArea = uitextarea(app.PracticeTrackerTab);
            app.InstrumentDropDownLabel = uilabel(app.PracticeTrackerTab);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.PracticeTrackerTab);
            app.InstrumentDropDown.Items = {'Guitar', 'Piano', 'Vocals', 'Trombone'};
            app.InstrumentDropDown.Value = 'Guitar';
            app.SubmitSessionButton = uibutton(app.PracticeTrackerTab, 'push');
            app.SubmitSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitSessionButtonPushed, true);
            app.SubmitSessionButton.Text = 'Submit Session';
            app.AMPMDropDown = uidropdown(app.PracticeTrackerTab);
            app.AMPMDropDown.Items = {'AM', 'PM'};
            app.AMPMDropDown.Value = 'AM';
            app.SongDatabaseButton = uibutton(app.PracticeTrackerTab, 'push');
            app.SongDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SongDatabaseButtonPushed, true);
            app.SongDatabaseButton.Text = 'Song Database';
            app.SongPieceDropDownLabel = uilabel(app.PracticeTrackerTab);
            app.SongPieceDropDownLabel.Text = 'Song/Piece';
            app.SongDropDown = uidropdown(app.PracticeTrackerTab);
            app.SongPieceDropDown_2Label = uilabel(app.PracticeTrackerTab);
            app.SongPieceDropDown_2Label.Text = 'Song/Piece';
            app.SongDropDown_2 = uidropdown(app.PracticeTrackerTab);
            app.SongPieceDropDown_3Label = uilabel(app.PracticeTrackerTab);
            app.SongPieceDropDown_3Label.Text = 'Song/Piece';
            app.SongDropDown_3 = uidropdown(app.PracticeTrackerTab);
            app.HoursEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.HoursEditFieldLabel.Text = 'Hours';
            app.HoursEditField = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.MinutesEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.MinutesEditFieldLabel.Text = 'Minutes';
            app.MinutesEditField = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.MinutesEditField_2Label = uilabel(app.PracticeTrackerTab);
            app.MinutesEditField_2Label.Text = 'Minutes';
            app.MinutesEditField_2 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.HoursEditField_2Label = uilabel(app.PracticeTrackerTab);
            app.HoursEditField_2Label.Text = 'Hours';
            app.HoursEditField_2 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.MinutesEditField_3Label = uilabel(app.PracticeTrackerTab);
            app.MinutesEditField_3Label.Text = 'Minutes';
            app.MinutesEditField_3 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.HoursEditField_3Label = uilabel(app.PracticeTrackerTab);
            app.HoursEditField_3Label.Text = 'Hours';
            app.HoursEditField_3 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.ClearInputsButton = uibutton(app.PracticeTrackerTab, 'push');
            app.ClearInputsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInputsButtonPushed, true);
            app.ClearInputsButton.Text = 'Clear Inputs';
            app.RecallSessionButton = uibutton(app.PracticeTrackerTab, 'push');
            app.RecallSessionButton.ButtonPushedFcn = createCallbackFcn(app, @RecallSessionButtonPushed, true);
            app.RecallSessionButton.Text = 'Recall Session';
            app.Circleof5thsTab = uitab(app.TabGroup);
            app.Circleof5thsTab.Title = 'Circle of 5ths';
            app.UIAxes = uiaxes(app.Circleof5thsTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.GuitarChordsTab = uitab(app.TabGroup);
            app.GuitarChordsTab.Title = 'Guitar Chords';
            app.GuitarChords_CAGEDLB = uilistbox(app.GuitarChordsTab);
            app.GuitarChords_CAGEDLB.Items = {'C', 'A', 'G', 'E', 'D'};
            app.GuitarChords_CAGEDLB.Value = 'C';
            app.GuitarChords_CO5Axes = uiaxes(app.GuitarChordsTab);
            app.GuitarChords_DisplayChordBtn = uibutton(app.GuitarChordsTab, 'push');
            app.GuitarChords_DisplayChordBtn.ButtonPushedFcn = createCallbackFcn(app, @GuitarChords_DisplayChordBtnButtonPushed, true);
            app.GuitarChords_DisplayChordBtn.Text = 'Display Chord';
            app.KeyDropDownLabel = uilabel(app.GuitarChordsTab);
            app.KeyDropDownLabel.Text = 'Key';
            app.GuitarChords_KeyDD = uidropdown(app.GuitarChordsTab);
            app.GuitarChords_KeyDD.Items = {'A', 'Bb', 'B', 'C', 'Db', 'D', 'Eb', 'E', 'F', 'Gb', 'G', 'Ab'};
            app.GuitarChords_KeyDD.ValueChangedFcn = createCallbackFcn(app, @GuitarChords_KeyDDValueChanged, true);
            app.GuitarChords_KeyDD.Value = 'A';
            app.ChordDropDownLabel = uilabel(app.GuitarChordsTab);
            app.ChordDropDownLabel.Text = 'Chord';
            app.GuitarChords_ChordDD = uidropdown(app.GuitarChordsTab);
            app.GuitarChords_ChordDD.Items = {'I', 'ii', 'iii', 'IV', 'V', 'vi', 'vii'};
            app.GuitarChords_ChordDD.ValueChangedFcn = createCallbackFcn(app, @GuitarChords_ChordDDValueChanged, true);
            app.GuitarChords_ChordDD.Value = 'I';
            app.SongDatabaseTab = uitab(app.TabGroup);
            app.SongDatabaseTab.Title = 'Song Database';
            app.AddSongButton = uibutton(app.SongDatabaseTab, 'push');
            app.AddSongButton.ButtonPushedFcn = createCallbackFcn(app, @AddSongButtonPushed, true);
            app.AddSongButton.Text = 'Add Song';
            app.UITable = uitable(app.SongDatabaseTab);
            app.UITable.ColumnName = {'Title'; 'Artist/Composer'; 'URL'};
            app.UITable.RowName = {};
            app.DeleteSongButton = uibutton(app.SongDatabaseTab, 'push');
            app.DeleteSongButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSongButtonPushed, true);
            app.DeleteSongButton.Text = 'Delete Song';
            app.SearchButton = uibutton(app.SongDatabaseTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.InstrumentsTab = uitab(app.TabGroup);
            app.InstrumentsTab.Title = 'Instruments';
            app.AddInstrumentButton = uibutton(app.InstrumentsTab, 'push');
            app.AddInstrumentButton.Text = 'Add Instrument';
            app.UITable2 = uitable(app.InstrumentsTab);
            app.UITable2.ColumnName = {'Instrument'; 'Hours Practiced'};
            app.UITable2.RowName = {};
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = timbr

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.timbrApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.timbrApp)
        end
    end
end